classdef classical_mechanics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DProjectileTab                  matlab.ui.container.Tab
        PartofMatlabPortfolioofJodEspanolaLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        FlightTimeLabel                 matlab.ui.control.Label
        RangeLabel                      matlab.ui.control.Label
        MaximumHeightLabel              matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        LaunchButton                    matlab.ui.control.Button
        AngleofProjectionEditField      matlab.ui.control.NumericEditField
        AngleofProjectionEditFieldLabel  matlab.ui.control.Label
        InitialSpeedEditField           matlab.ui.control.NumericEditField
        InitialSpeedEditFieldLabel      matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        SimplePendulumTab               matlab.ui.container.Tab
        PartofMatlabPortfolioofJodEspanolaLabel_2  matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        MaxYDisplacementEditField       matlab.ui.control.NumericEditField
        MaxYDisplacementEditFieldLabel  matlab.ui.control.Label
        MaxXDisplacementEditField       matlab.ui.control.NumericEditField
        MaxXDisplacementEditFieldLabel  matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        theta0Label                     matlab.ui.control.Label
        y0Label                         matlab.ui.control.Label
        x0Label                         matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        StartButton                     matlab.ui.control.StateButton
        InitialAngleEditField           matlab.ui.control.NumericEditField
        InitialAngleEditFieldLabel      matlab.ui.control.Label
        LineLengthEditField             matlab.ui.control.NumericEditField
        LineLengthEditFieldLabel        matlab.ui.control.Label
        WaterDischargeTab               matlab.ui.container.Tab
        PartofMatlabPortfolioofJodEspanolaLabel_3  matlab.ui.control.Label
        BottomViewPanel                 matlab.ui.container.Panel
        ControlPanel                    matlab.ui.container.Panel
        DischargeFactorEditField        matlab.ui.control.NumericEditField
        DischargeFactorEditFieldLabel   matlab.ui.control.Label
        HoleDiameterSlider              matlab.ui.control.Slider
        HoleDiameterSliderLabel         matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        DiametermEditField              matlab.ui.control.NumericEditField
        DiametermEditFieldLabel         matlab.ui.control.Label
        FinalHeightSlider               matlab.ui.control.Slider
        FinalHeightSliderLabel          matlab.ui.control.Label
        SImulatorPanel                  matlab.ui.container.Panel
        RunButton                       matlab.ui.control.StateButton
        Timetoreachfinalheight0sLabel   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Gravity = 9.81;
        
        %For 2D projectile
        Initial_Speed = 0;
        Projection_Angle = 0;
        
        %For simple pendulum
        Length = 10;
        Initial_theta = 40;
        theta_list;
        value_changed = false;
        pendulum_f1;pendulum_f2;pendulum_f3;x;y;
        
        %For water discharge
        Height = 1;
        Final_Height = 0;
        Diameter = 0.16;
        Hole_Diameter = 0.0016;
        Discharge_Factor = 0.62;
        wd_f1;wd_f2;wd_f3;wd_f4;wd_f5;tt;
        time = 0;
        second = 0;
        minute = 0;
        wd_value_changed = false;
    end
    
    methods (Access = private)
        
        function run_simulation(app)
            T1 = (2*pi)*((app.Length/app.Gravity)^0.5);
            syms n;
            M1 = factorial(2*n)/(((2^n)*factorial(n))^2)*((sin(app.Initial_theta/2))^2*n);
            M2 = symsum(M1,0,inf);
            T2 = double(T1*M2);
            D2 = ((T2/T1)/T2)*100;
            
            T3 = (4*T1)*((1+sqrt(cos(app.Initial_theta/2)))^-2);
            D3 = ((T3-T1)/T3)*100;
            
            app.Panel.AutoResizeChildren = 'off';
            graph = subplot(1,1,1,'Parent',app.Panel);
            cla(graph);
            app.pendulum_f1 = line(graph,[-app.Length app.Length],[app.Length app.Length],'linestyle','-','color','k','linewidth',6);
            app.pendulum_f2 = line(graph,[0 0],[app.Length 0],'linestyle','-','color','k','linewidth',2);
            app.pendulum_f3 = line(graph,0,0,'linestyle','none','marker','.','markersize',100,'color','b');
            axis(graph,[-1.3*app.Length 1.3*app.Length -1.3*app.Length 1.3*app.Length]);
            axis(graph,'equal')

            title(graph,'\itSimple Pendulum Motion','FontSize',10);
            box(graph,'on');
            grid(graph,'on');
            hold(graph,'on');
            
            app.theta_list = linspace(app.Initial_theta,-app.Initial_theta ,100);
            for i = 1:length(app.theta_list)
                app.x(i) = app.Length*sind(app.theta_list(i));
                app.y(i) = app.Length-app.Length*cosd(app.theta_list(i));
            end
            app.MaxXDisplacementEditField.Value = max(app.x);
            app.MaxYDisplacementEditField.Value = max(app.y);
        end
        
        function run_app_visualization(app)
            l = [1:length(app.theta_list),length(app.theta_list):-1:1];
            app.StatusLamp.Color = [0.00,1.00,0.00];
            while (app.StartButton.Value)
                for j = 1:length(l)
                    i = l(j);
                    set(app.pendulum_f1,'xdata',[-app.Length app.Length],'ydata',[app.Length app.Length]);
                    set(app.pendulum_f2,'xdata',[0 app.x(i)],'ydata',[app.Length app.y(i)]);
                    set(app.pendulum_f3,'xdata',app.x(i),'ydata',app.y(i));
                    app.x0Label.Text = strcat('x : ', num2str(app.x(i)));
                    app.y0Label.Text = strcat('y : ', num2str(app.y(i)));
                    app.theta0Label.Text = strcat('theta : ', num2str(app.theta_list(i)));
                    pause(0.1);
                    if ~app.StartButton.Value
                        break;
                    end
                end
            end
        end
        
        function draw_tank(app)
            cla(app.UIAxes);
            app.wd_f1 = line(app.UIAxes,[-app.Hole_Diameter/2 -app.Diameter/2],[0 0],'linestyle','-','color','k','linewidth',4);
            app.wd_f2 = line(app.UIAxes,[-app.Diameter/2 -app.Diameter/2],[0, app.Height],'linestyle','-','color','k','linewidth',4);
            app.wd_f3 = line(app.UIAxes,[app.Hole_Diameter/2 app.Diameter/2],[0 0],'linestyle','-','color','k','linewidth',4);
            app.wd_f4 = line(app.UIAxes,[app.Diameter/2 app.Diameter/2],[0 app.Height],'linestyle','-','color','k','linewidth',4);
            app.wd_f5 = line(app.UIAxes,[-app.Diameter/2 app.Diameter/2],[app.Height app.Height],'linestyle','-','color','k','linewidth',4);
            axis(app.UIAxes,[-1.2*app.Diameter 1.2*app.Diameter -0.2*app.Height 1.2*app.Height]);
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            app.tt = title(app.UIAxes,sprintf('Time: %0.4f sec', 0),'FontSize',14);
            patch(app.UIAxes, [-app.Diameter/2 app.Diameter/2 app.Diameter/2 -app.Diameter/2],[0 0 app.Height app.Height],'blue');
            fill(app.UIAxes, [-app.Diameter/2 app.Diameter/2 app.Diameter/2 -app.Diameter/2],[0 0 app.Height app.Height],'white');
            fill(app.UIAxes, [-app.Diameter/2 app.Diameter/2 app.Diameter/2 -app.Diameter/2],[0 0 app.Final_Height app.Final_Height],'b')
        end
        
        function run_wd_simulation(app)
            syms z
            A = pi*(app.Diameter^2)/4;
            ao = pi*(app.Hole_Diameter^2)/4;

            Eq = @(z) -(A./(ao.*app.Discharge_Factor)).*1./(sqrt(2.*app.Gravity.*z));
            app.time = integral(Eq,app.Height,app.Final_Height);
            app.minute = fix(app.time/60);
            app.second = (app.time/60 - app.minute)*60;
            app.Timetoreachfinalheight0sLabel.Text = strcat('Time to reach final height : ', num2str(app.time),' s');
        end
        
        function run_wd_visualization(app)
            t1 = linspace(0,app.time,100);
            for r = 1:length(t1)
                pause(0.1);
                if ~app.RunButton.Value
                    break;
                end
                K = (1/app.Discharge_Factor)*((app.Diameter/app.Hole_Diameter)^2)*((2/app.Gravity)^0.5);
                h1(r) = (sqrt(app.Height)-((t1(r)/K)))^2;
                set(app.wd_f1,'xdata',[-app.Hole_Diameter/2 -app.Diameter/2],'ydata',[0 0]);
                set(app.wd_f2,'xdata',[-app.Diameter/2 -app.Diameter/2],'ydata',[0 app.Height]);
                set(app.wd_f3,'xdata',[app.Hole_Diameter/2 app.Diameter/2],'ydata',[0 0]);
                set(app.wd_f4,'xdata',[app.Diameter/2 app.Diameter/2],'ydata',[0 app.Height]);
                set(app.wd_f5,'xdata',[-app.Diameter/2 app.Diameter/2],'ydata',[h1(r) h1(r)]);
                set(app.tt, 'String', sprintf('Time: %0.4f sec', t1(r)));
                fill(app.UIAxes, [-app.Diameter/2 app.Diameter/2 app.Diameter/2 -app.Diameter/2],[0 0 app.Height app.Height],'white');
                fill(app.UIAxes, [-app.Diameter/2 app.Diameter/2 app.Diameter/2 -app.Diameter/2],[0 0 h1(r) h1(r)],'b')
            end
            app.RunButton.Value = false;
        end
        
        function draw_bottom(app)
            app.BottomViewPanel.AutoResizeChildren = 'off';
            bottom_view = subplot(1,1,1,'Parent',app.BottomViewPanel);
            cla(bottom_view);
            rectangle(bottom_view,'Position',[-app.Diameter/2 -app.Diameter/2 app.Diameter app.Diameter], ...
                'Curvature',[1 1], 'FaceColor','b','LineWidth',2)
            rectangle(bottom_view,'Position',[-app.Hole_Diameter/2 -app.Hole_Diameter/2 app.Hole_Diameter app.Hole_Diameter], ...
                'Curvature',[1 1],'FaceColor',[0,0,0],'LineWidth',2)
        end
        
        function draw_projectile(app)
            fspec = '%.2f';
            theta = app.Projection_Angle * (pi/180);
            possible_time = roots([-0.5*app.Gravity, app.Initial_Speed*sin(theta) 0]);
            if possible_time(1) <= 0
                flight_time = possible_time(2);
            else
                flight_time = possible_time(1);
            end
            
            max_h = ((app.Initial_Speed*sin(theta))^2)/(2*app.Gravity);
            range = app.Initial_Speed*flight_time*cos(theta);
            t = linspace(0,flight_time,200);
            
            xi = app.Initial_Speed*t(1)*cos(theta);
            yi = app.Initial_Speed*t(1)*sin(theta) - 0.5*app.Gravity*(t(1)^2);
            time_of_max_height = (app.Initial_Speed*sin(theta))/app.Gravity;
            color = abs(get_color(app));
            set_limit(app,range,max_h);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,xi,yi,'.',"MarkerEdgeColor",color,"MarkerFaceColor",color);
            if range > 0
                hold(app.UIAxes2,'on');
                for i = 2:length(t)
                    xi = app.Initial_Speed*t(i)*cos(theta);
                    yi = app.Initial_Speed*t(i)*sin(theta) - 0.5*app.Gravity*(t(i)^2);
                    if t(i) > time_of_max_height
                        app.MaximumHeightLabel.Text = strcat('Maximum Height : ', num2str(max_h,fspec));
                    else
                        app.MaximumHeightLabel.Text = strcat('Maximum Height : ', num2str(yi,fspec));
                    end
                    
                    app.RangeLabel.Text = strcat('Range :', num2str(xi,fspec));
                    app.FlightTimeLabel.Text = strcat('Flight Time :', num2str(t(i),fspec));
                    pause(0.00001);
                    plot(app.UIAxes2,xi,yi,'.',"MarkerEdgeColor",color,"MarkerFaceColor",color);
                    hold(app.UIAxes2,'on');
                    legend(app.UIAxes2,{strcat('time: ',num2str(t(i)))}, 'Location', 'northwest');
                end
            end
        end
        
        function color_map = get_color(app)
            color_map = [rand rand rand];
        end
        
        function set_limit(app,max_x,max_y)
            x_lim = get(app.UIAxes2, 'XLim');
            x_lim(2)
            if x_lim(2) < max_x
                xlim(app.UIAxes2,[0, max_x]);
            end
            y_lim = get(app.UIAxes2, 'YLim');
            if y_lim < max_y
                ylim(app.UIAxes2,[0, max_y]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value && (app.value_changed || (length(app.theta_list) <= 1))
                run_simulation(app)
                app.value_changed = false;
            end
            if value
                run_app_visualization(app)
            else
                app.StatusLamp.Color = [1.00,0.00,0.00];
            end
        end

        % Value changed function: LineLengthEditField
        function LineLengthEditFieldValueChanged(app, event)
            value = app.LineLengthEditField.Value;
            app.Length = value;
            app.value_changed = true;
        end

        % Value changed function: InitialAngleEditField
        function InitialAngleEditFieldValueChanged(app, event)
            value = app.InitialAngleEditField.Value;
            app.Initial_theta = value;
            app.value_changed = true;
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            value = app.HeightmEditField.Value;
            app.Height = value;
            app.wd_value_changed = true;
            app.FinalHeightSlider.Limits = [0 app.Height];
            draw_tank(app)
        end

        % Value changed function: DiametermEditField
        function DiametermEditFieldValueChanged(app, event)
            value = app.DiametermEditField.Value;
            app.Diameter = value;
            app.wd_value_changed = true;
            app.HoleDiameterSlider.Limits = [0.01*app.Diameter app.Diameter];
            draw_tank(app)
            draw_bottom(app);
        end

        % Value changed function: DischargeFactorEditField
        function DischargeFactorEditFieldValueChanged(app, event)
            value = app.DischargeFactorEditField.Value;
            app.Discharge_Factor = value;
            app.wd_value_changed = true;
        end

        % Value changed function: FinalHeightSlider
        function FinalHeightSliderValueChanged(app, event)
            value = app.FinalHeightSlider.Value;
            app.Final_Height = value;
            app.wd_value_changed = true;
            draw_tank(app)
        end

        % Value changed function: HoleDiameterSlider
        function HoleDiameterSliderValueChanged(app, event)
            value = app.HoleDiameterSlider.Value;
            app.Hole_Diameter = value;
            app.wd_value_changed = true;
            draw_tank(app)
            draw_bottom(app);
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value && (app.value_changed || (length(app.theta_list) <= 1))
                run_wd_simulation(app);
                app.wd_value_changed = false;
            end
            if value
                run_wd_visualization(app);
            end
        end

        % Button down function: WaterDischargeTab
        function WaterDischargeTabButtonDown(app, event)
            clc; clear('app.DProjectileTab'); clear('app.SimplePendulumTab');  
            draw_tank(app);
            draw_bottom(app);
        end

        % Value changed function: InitialSpeedEditField
        function InitialSpeedEditFieldValueChanged(app, event)
            value = app.InitialSpeedEditField.Value;
            app.Initial_Speed = value;
        end

        % Value changed function: AngleofProjectionEditField
        function AngleofProjectionEditFieldValueChanged(app, event)
            value = app.AngleofProjectionEditField.Value;
            app.Projection_Angle = value;
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            draw_projectile(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes2);
        end

        % Button down function: SimplePendulumTab
        function SimplePendulumTabButtonDown(app, event)
            clc; clear('app.DProjectileTab'); clear('app.WaterDischargeTab');  
        end

        % Button down function: DProjectileTab
        function DProjectileTabButtonDown(app, event)
            clc; clear('app.SimplePendulumTab'); clear('app.WaterDischargeTab'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DProjectileTab = uitab(app.TabGroup);
            app.DProjectileTab.Title = '2D Projectile';
            app.DProjectileTab.ButtonDownFcn = createCallbackFcn(app, @DProjectileTabButtonDown, true);
            app.Panel_2 = uipanel(app.DProjectileTab);
            app.UIAxes2 = uiaxes(app.Panel_2);
            app.InitialSpeedEditFieldLabel = uilabel(app.DProjectileTab);
            app.InitialSpeedEditFieldLabel.Text = 'Initial Speed';
            app.InitialSpeedEditField = uieditfield(app.DProjectileTab, 'numeric');
            app.InitialSpeedEditField.Limits = [0 Inf];
            app.InitialSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @InitialSpeedEditFieldValueChanged, true);
            app.AngleofProjectionEditFieldLabel = uilabel(app.DProjectileTab);
            app.AngleofProjectionEditFieldLabel.Text = 'Angle of Projection';
            app.AngleofProjectionEditField = uieditfield(app.DProjectileTab, 'numeric');
            app.AngleofProjectionEditField.Limits = [0 90];
            app.AngleofProjectionEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofProjectionEditFieldValueChanged, true);
            app.LaunchButton = uibutton(app.DProjectileTab, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.ClearButton = uibutton(app.DProjectileTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Panel_3 = uipanel(app.DProjectileTab);
            app.MaximumHeightLabel = uilabel(app.Panel_3);
            app.MaximumHeightLabel.Text = 'Maximum Height : ';
            app.RangeLabel = uilabel(app.Panel_3);
            app.RangeLabel.Text = 'Range :';
            app.FlightTimeLabel = uilabel(app.Panel_3);
            app.FlightTimeLabel.Text = 'Flight Time :';
            app.PartofMatlabPortfolioofJodEspanolaLabel = uilabel(app.DProjectileTab);
            app.PartofMatlabPortfolioofJodEspanolaLabel.Text = 'Part of Matlab Portfolio of Jod Espanola';
            app.SimplePendulumTab = uitab(app.TabGroup);
            app.SimplePendulumTab.Title = 'Simple Pendulum';
            app.SimplePendulumTab.ButtonDownFcn = createCallbackFcn(app, @SimplePendulumTabButtonDown, true);
            app.InputPanel = uipanel(app.SimplePendulumTab);
            app.InputPanel.Title = 'Input';
            app.LineLengthEditFieldLabel = uilabel(app.InputPanel);
            app.LineLengthEditFieldLabel.Text = 'Line Length';
            app.LineLengthEditField = uieditfield(app.InputPanel, 'numeric');
            app.LineLengthEditField.Limits = [0 Inf];
            app.LineLengthEditField.ValueChangedFcn = createCallbackFcn(app, @LineLengthEditFieldValueChanged, true);
            app.LineLengthEditField.Value = 10;
            app.InitialAngleEditFieldLabel = uilabel(app.InputPanel);
            app.InitialAngleEditFieldLabel.Text = 'Initial Angle';
            app.InitialAngleEditField = uieditfield(app.InputPanel, 'numeric');
            app.InitialAngleEditField.Limits = [0 360];
            app.InitialAngleEditField.ValueChangedFcn = createCallbackFcn(app, @InitialAngleEditFieldValueChanged, true);
            app.InitialAngleEditField.Value = 40;
            app.StartButton = uibutton(app.InputPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.OutputPanel = uipanel(app.SimplePendulumTab);
            app.OutputPanel.Title = 'Output';
            app.StatusLampLabel = uilabel(app.OutputPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.OutputPanel);
            app.x0Label = uilabel(app.OutputPanel);
            app.x0Label.Text = 'x : 0';
            app.y0Label = uilabel(app.OutputPanel);
            app.y0Label.Text = 'y : 0';
            app.theta0Label = uilabel(app.OutputPanel);
            app.theta0Label.Text = 'theta : 0';
            app.Panel = uipanel(app.OutputPanel);
            app.ParametersPanel = uipanel(app.SimplePendulumTab);
            app.ParametersPanel.Title = 'Parameters';
            app.MaxXDisplacementEditFieldLabel = uilabel(app.ParametersPanel);
            app.MaxXDisplacementEditFieldLabel.Text = 'Max. X Displacement';
            app.MaxXDisplacementEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MaxYDisplacementEditFieldLabel = uilabel(app.ParametersPanel);
            app.MaxYDisplacementEditFieldLabel.Text = 'Max. Y Displacement';
            app.MaxYDisplacementEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PartofMatlabPortfolioofJodEspanolaLabel_2 = uilabel(app.SimplePendulumTab);
            app.PartofMatlabPortfolioofJodEspanolaLabel_2.Text = 'Part of Matlab Portfolio of Jod Espanola';
            app.WaterDischargeTab = uitab(app.TabGroup);
            app.WaterDischargeTab.Title = 'Water Discharge';
            app.WaterDischargeTab.ButtonDownFcn = createCallbackFcn(app, @WaterDischargeTabButtonDown, true);
            app.SImulatorPanel = uipanel(app.WaterDischargeTab);
            app.SImulatorPanel.Title = 'SImulator';
            app.UIAxes = uiaxes(app.SImulatorPanel);
            app.Timetoreachfinalheight0sLabel = uilabel(app.SImulatorPanel);
            app.Timetoreachfinalheight0sLabel.Text = 'Time to reach final height : 0 s';
            app.RunButton = uibutton(app.SImulatorPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.ControlPanel = uipanel(app.WaterDischargeTab);
            app.ControlPanel.Title = 'Control Panel';
            app.FinalHeightSliderLabel = uilabel(app.ControlPanel);
            app.FinalHeightSliderLabel.Text = {'Final'; 'Height'};
            app.FinalHeightSlider = uislider(app.ControlPanel);
            app.FinalHeightSlider.Limits = [0 1];
            app.FinalHeightSlider.ValueChangedFcn = createCallbackFcn(app, @FinalHeightSliderValueChanged, true);
            app.DiametermEditFieldLabel = uilabel(app.ControlPanel);
            app.DiametermEditFieldLabel.Text = 'Diameter (m)';
            app.DiametermEditField = uieditfield(app.ControlPanel, 'numeric');
            app.DiametermEditField.Limits = [0 Inf];
            app.DiametermEditField.ValueChangedFcn = createCallbackFcn(app, @DiametermEditFieldValueChanged, true);
            app.DiametermEditField.Value = 0.16;
            app.HeightmEditFieldLabel = uilabel(app.ControlPanel);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.ControlPanel, 'numeric');
            app.HeightmEditField.Limits = [0 Inf];
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Value = 1;
            app.HoleDiameterSliderLabel = uilabel(app.ControlPanel);
            app.HoleDiameterSliderLabel.Text = {'Hole '; 'Diameter'};
            app.HoleDiameterSlider = uislider(app.ControlPanel);
            app.HoleDiameterSlider.Limits = [0.0016 0.16];
            app.HoleDiameterSlider.ValueChangedFcn = createCallbackFcn(app, @HoleDiameterSliderValueChanged, true);
            app.HoleDiameterSlider.Value = 0.0016;
            app.DischargeFactorEditFieldLabel = uilabel(app.ControlPanel);
            app.DischargeFactorEditFieldLabel.Text = 'Discharge Factor';
            app.DischargeFactorEditField = uieditfield(app.ControlPanel, 'numeric');
            app.DischargeFactorEditField.Limits = [0 Inf];
            app.DischargeFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DischargeFactorEditFieldValueChanged, true);
            app.DischargeFactorEditField.Value = 0.62;
            app.BottomViewPanel = uipanel(app.WaterDischargeTab);
            app.BottomViewPanel.Title = 'Bottom View';
            app.PartofMatlabPortfolioofJodEspanolaLabel_3 = uilabel(app.WaterDischargeTab);
            app.PartofMatlabPortfolioofJodEspanolaLabel_3.Text = 'Part of Matlab Portfolio of Jod Espanola';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classical_mechanics

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end