classdef noise_addition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalNoiseGeneratorUIFigure  matlab.ui.Figure
        PartofMatlabPortfolioofJodEspanolaLabel  matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        FileSelectedTextArea          matlab.ui.control.TextArea
        FileSelectedLabel             matlab.ui.control.Label
        NoisePanel                    matlab.ui.container.Panel
        AddNoiseButton                matlab.ui.control.StateButton
        NoiseTypeDropDown             matlab.ui.control.DropDown
        NoiseTypeDropDownLabel        matlab.ui.control.Label
        GenerateNoiseButton           matlab.ui.control.Button
        ControlPanelLabel             matlab.ui.control.Label
        AddFileButton                 matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        SignalTab                     matlab.ui.container.Tab
        Panel_2                       matlab.ui.container.Panel
        PlaySignalButton              matlab.ui.control.StateButton
        NoiseTab                      matlab.ui.container.Tab
        NoiseDistributionPanel        matlab.ui.container.Panel
        StandardDeviationSlider       matlab.ui.control.Slider
        StandardDeviationSliderLabel  matlab.ui.control.Label
        MeanSlider                    matlab.ui.control.Slider
        MeanSliderLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        amplitude = 0.0; % height of the signal
        time = 0.0; % time
        type = 'Gaussian'; %type of noise
        mu = 0;
        sigma = 1;
        noise = 0.0; % additive noise
        sampling_rate = 0;
        file_uploaded = false;
        noise_added = false;
    end
    
    methods (Access = public)
        
        function n = noiseMaker(app)
            if strcmp(app.type,'Gaussian')
                n = app.sigma * randn(size(app.amplitude,1),size(app.amplitude,2)) + app.mu;
            else
                n = app.sigma * rand(size(app.amplitude,1),size(app.amplitude,2)) + app.mu;
            end
        end
        
        function showNoise(app)
            if size(app.noise,2) == 2
                app.NoiseDistributionPanel.AutoResizeChildren = 'off';
                channel1 = subplot(2,1,1,'Parent',app.NoiseDistributionPanel);
                cla(channel1);
                [f1,x1] = hist(app.noise(:,1),100);
                bar(channel1,x1,f1/trapz(x1,f1));
                grid(channel1,'on');
                xlabel(channel1,'Bins');
                title(channel1,'Left Channel Noise Distribution',app.type);
                
                if strcmp(app.type,'Gaussian')
                    hold(channel1,'on');
                    g1 = (1/(sqrt(2*pi)*app.sigma))*exp(-((x1-app.mu).^2)/(2*app.sigma^2));
                    plot(channel1,x1,g1);
                    legend(channel1,'Histogram','Theoretical PDF');
                    hold(channel1,'off');
                else
                    legend(channel1,'Histogram');
                end
                
                channel2 = subplot(2,1,2,'Parent',app.NoiseDistributionPanel);
                cla(channel2);
                [f2,x2] = hist(app.noise(:,2),100);
                bar(channel2,x2,f2/trapz(x2,f2));
                grid(channel2,'on');
                xlabel(channel2,'Bins');
                title(channel2,'Right Channel Noise Distribution',app.type);
                
                if strcmp(app.type,'Gaussian')
                    hold(channel2,'on');
                    g2 = (1/(sqrt(2*pi)*app.sigma))*exp(-((x2-app.mu).^2)/(2*app.sigma^2));
                    plot(channel2,x2,g2);
                    legend(channel2,'Histogram','Theoretical PDF');
                    hold(channel2,'off');
                else
                    legend(channel2,'Histogram');
                end
            else
                app.NoiseDistributionPanel.AutoResizeChildren = 'off';
                channel = subplot(1,1,1,'Parent',app.NoiseDistributionPanel);
                cla(channel);
                [f,x] = hist(app.noise(:,1),100);
                bar(channel,x,fs/trapz(x,f));
                grid(channel,'on');
                xlabel(channel,'Bins');
                
                if strcmp(app.type,'Gaussian')
                    hold(channel,'on');
                    g = (1/(sqrt(2*pi)*app.sigma))*exp(-((x-app.mu).^2)/(2*app.sigma^2));
                    plot(channel,x,g);
                    legend(channel,'Histogram','Theoretical PDF');
                    hold(channel,'off');
                else
                    legend(channel,'Histogram');
                end
            end
        end
        
        function showSignal(app)
            if size(app.amplitude,2) == 2
                app.Panel_2.AutoResizeChildren = 'off';
                channel1 = subplot(2,1,1,'Parent',app.Panel_2);
                channel2 = subplot(2,1,2,'Parent',app.Panel_2);
                cla(channel1);
                cla(channel2);
                plot(channel1, app.time, app.amplitude(:,1));
                xlabel(channel1,'time (s)');
                grid(channel1,'on');
                title(channel1,'Left Channel Signal Waveform');
                plot(channel2, app.time, app.amplitude(:,2));
                xlabel(channel2,'time (s)');
                grid(channel2,'on');
                title(channel2,'Right Channel Signal Waveform');
            else
                app.Panel_2.AutoResizeChildren = 'off';
                channel = subplot(1,1,1,'Parent',app.Panel_2);
                cla(channel);
                plot(channel, app.time, app.amplitude(:,1));
                xlabel(channel,'time (s)');
                grid(channel,'on');
                title(channel,'Signal Waveform');
            end
        end
        
        function createNoise(app)
            app.noise = noiseMaker(app);
            showNoise(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddFileButton
        function AddFileButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*'}, 'Select file');
            drawnow;
            figure(app.SignalNoiseGeneratorUIFigure);
            
            if ~isequal(filename,0)
                file = strcat(filepath, filename);
                app.FileSelectedTextArea.Value = file;
                [app.amplitude, app.sampling_rate] = audioread(file);
                app.time = (1:length(app.amplitude))/app.sampling_rate;
                showSignal(app);
                app.file_uploaded = true;
                app.PlaySignalButton.Enable = 'on';
                app.PlaySignalButton.Visible = 'on';
                app.GenerateNoiseButton.Enable = 'on';
                app.AddNoiseButton.Enable = 'on';
            end
        end

        % Value changed function: AddNoiseButton
        function AddNoiseButtonValueChanged(app, event)
            value = app.AddNoiseButton.Value;
            if app.file_uploaded
                if value
                    app.amplitude = app.amplitude + app.noise;
                    app.noise_added = true;
                    showSignal(app);
                else
                    app.amplitude = app.amplitude - app.noise;
                    app.noise_added = false;
                    showSignal(app);
                end
            end
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            value = app.NoiseTypeDropDown.Value;
            app.type = value;
            if app.file_uploaded
                createNoise(app);
            end
        end

        % Value changed function: PlaySignalButton
        function PlaySignalButtonValueChanged(app, event)
            value = app.PlaySignalButton.Value;
            if app.file_uploaded
                if value
                    sound(app.amplitude,app.sampling_rate);
                else
                    clear sound;
                end
            end
        end

        % Button pushed function: GenerateNoiseButton
        function GenerateNoiseButtonPushed(app, event)
            if app.file_uploaded
                createNoise(app);
            end
        end

        % Callback function
        function StandardDeviationSliderValueChanged(app, event)
            value = app.StandardDeviationSlider.Value;
            app.sigma = value;
            if app.file_uploaded
                createNoise(app);
            end
        end

        % Value changed function: MeanSlider
        function MeanSliderValueChanged(app, event)
            value = app.MeanSlider.Value;
            app.mu = value;
            if app.file_uploaded
                createNoise(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalNoiseGeneratorUIFigure = uifigure('Visible', 'off');
            app.SignalNoiseGeneratorUIFigure.Name = 'Signal Noise Generator';
            app.TabGroup = uitabgroup(app.SignalNoiseGeneratorUIFigure);
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.PlaySignalButton = uibutton(app.SignalTab, 'state');
            app.PlaySignalButton.ValueChangedFcn = createCallbackFcn(app, @PlaySignalButtonValueChanged, true);
            app.PlaySignalButton.Enable = 'off';
            app.PlaySignalButton.Visible = 'off';
            app.PlaySignalButton.Text = 'Play Signal';
            app.Panel_2 = uipanel(app.SignalTab);
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.MeanSliderLabel = uilabel(app.NoiseTab);
            app.MeanSliderLabel.Text = 'Mean';
            app.MeanSlider = uislider(app.NoiseTab);
            app.MeanSlider.ValueChangedFcn = createCallbackFcn(app, @MeanSliderValueChanged, true);
            app.StandardDeviationSliderLabel = uilabel(app.NoiseTab);
            app.StandardDeviationSliderLabel.Text = 'Standard Deviation';
            app.StandardDeviationSlider = uislider(app.NoiseTab);
            app.StandardDeviationSlider.Limits = [1 100];
            app.StandardDeviationSlider.Value = 1;
            app.NoiseDistributionPanel = uipanel(app.NoiseTab);
            app.NoiseDistributionPanel.Title = 'Noise Distribution';
            app.Panel_3 = uipanel(app.SignalNoiseGeneratorUIFigure);
            app.AddFileButton = uibutton(app.Panel_3, 'push');
            app.AddFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFileButtonPushed, true);
            app.AddFileButton.Text = 'Add File';
            app.ControlPanelLabel = uilabel(app.Panel_3);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.NoisePanel = uipanel(app.Panel_3);
            app.NoisePanel.Title = 'Noise Panel';
            app.GenerateNoiseButton = uibutton(app.NoisePanel, 'push');
            app.GenerateNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNoiseButtonPushed, true);
            app.GenerateNoiseButton.Enable = 'off';
            app.GenerateNoiseButton.Text = 'Generate Noise';
            app.NoiseTypeDropDownLabel = uilabel(app.NoisePanel);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.NoisePanel);
            app.NoiseTypeDropDown.Items = {'Gaussian', 'Normal'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'Gaussian';
            app.AddNoiseButton = uibutton(app.NoisePanel, 'state');
            app.AddNoiseButton.ValueChangedFcn = createCallbackFcn(app, @AddNoiseButtonValueChanged, true);
            app.AddNoiseButton.Enable = 'off';
            app.AddNoiseButton.Text = 'Add Noise';
            app.FileSelectedLabel = uilabel(app.Panel_3);
            app.FileSelectedLabel.Text = {'File '; 'Selected'};
            app.FileSelectedTextArea = uitextarea(app.Panel_3);
            app.FileSelectedTextArea.Value = {'None'};
            app.PartofMatlabPortfolioofJodEspanolaLabel = uilabel(app.SignalNoiseGeneratorUIFigure);
            app.PartofMatlabPortfolioofJodEspanolaLabel.Text = 'Part of Matlab Portfolio of Jod Espanola';
            % Show the figure after all components are created
            app.SignalNoiseGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise_addition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalNoiseGeneratorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalNoiseGeneratorUIFigure)
        end
    end
end