classdef projection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PartofMatlabPortfolioofJodEspanolaLabel  matlab.ui.control.Label
        InitialElevationPanel           matlab.ui.container.Panel
        FinishButton                    matlab.ui.control.Button
        EnterElevationEditField         matlab.ui.control.NumericEditField
        EnterElevationEditFieldLabel    matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        DistanceTextArea                matlab.ui.control.TextArea
        DistanceTextAreaLabel           matlab.ui.control.Label
        HeightTextArea                  matlab.ui.control.TextArea
        HeightTextAreaLabel             matlab.ui.control.Label
        ElevatedCheckBox                matlab.ui.control.CheckBox
        Switch                          matlab.ui.control.RockerSwitch
        InitialSpeedKnob                matlab.ui.control.Knob
        InitialSpeedKnobLabel           matlab.ui.control.Label
        AngleofProjectionKnob           matlab.ui.control.Knob
        AngleofProjectionKnobLabel      matlab.ui.control.Label
        SimulationSummaryPanel          matlab.ui.container.Panel
        SearchValuePanel                matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        timeEditField                   matlab.ui.control.NumericEditField
        timeEditFieldLabel              matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        CloseSummaryButton              matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        FlightTimeTextArea              matlab.ui.control.TextArea
        FlightTimeTextAreaLabel         matlab.ui.control.Label
        TimeTextArea                    matlab.ui.control.TextArea
        TimeTextAreaLabel               matlab.ui.control.Label
        RangeTextArea                   matlab.ui.control.TextArea
        RangeTextAreaLabel              matlab.ui.control.Label
        MaxHeightTextArea               matlab.ui.control.TextArea
        MaxHeightLabel                  matlab.ui.control.Label
        InputPanel                      matlab.ui.container.Panel
        InitialSpeedTextArea            matlab.ui.control.TextArea
        InitialSpeedTextAreaLabel       matlab.ui.control.Label
        AngleofProjectionTextArea       matlab.ui.control.TextArea
        AngleofProjectionTextAreaLabel  matlab.ui.control.Label
        ProjectilePanel                 matlab.ui.container.Panel
    end

    
    properties (Access = private)
        gravity = 9.81;
        speed = 0;
        theta = 0;
        elevated = false;
        flight_time = 0;
        range = 0;
        max_height = 0;
        elevation = 0;
    end
    
    methods (Access = private)
        
        function start_simulation(app)
            fspec = '%.2f';
            app.ProjectilePanel.AutoResizeChildren = 'off';
            graph = subplot(1,1,1,'Parent',app.ProjectilePanel);
            speed = app.speed;
            theta = app.theta * (pi/180);
            gravity = app.gravity;
            elevation = app.elevation;
            cla(graph);
            possible_time = roots([-0.5*gravity, speed*sin(theta), elevation]);
            if possible_time(1) <= 0
                flight_time = possible_time(2);
            else
                flight_time = possible_time(1);
            end
            
            max_height = app.elevation + ((speed*sin(theta))^2)/(2*gravity);
            range = speed*flight_time*cos(theta);
            time = linspace(0,flight_time,200);
            
            app.flight_time = flight_time;
            app.range = range;
            app.max_height = app.elevation + max_height;
            
            xi = speed*time(1)*cos(theta);
            yi = app.elevation + speed*time(1)*sin(theta) - 0.5*gravity*(time(1)^2);
            
            app.DistanceTextArea.Value = {num2str(xi,fspec)};
            app.HeightTextArea.Value = {num2str(yi,fspec)};
            plot(graph,xi,yi,'o');
            if range > 0
                xlim(graph,[0,range]);
                ylim(graph,[0,max_height+10]);
                hold(graph,'on');
                for i = 2:length(time)
                    xi = speed*time(i)*cos(theta);
                    yi = app.elevation + speed*time(i)*sin(theta) - 0.5*gravity*(time(i)^2);
                    app.DistanceTextArea.Value = {num2str(xi,fspec)};
                    app.HeightTextArea.Value = {num2str(yi,fspec)};
                    pause(0.00001);
                    plot(graph,xi,yi,'b-o');
                    hold(graph,'on');
                    legend(graph,{strcat('time: ',num2str(time(i)))}, 'Location', 'northwest');
                end
            end
            app.HeightTextArea.Value = {num2str(0,fspec)};
            open_summary(app);
            app.Switch.Value = 'Off';
        end
        
        function open_summary(app)
            fspec = '%.2f';
            app.SimulationSummaryPanel.Enable = 'on';
            app.SimulationSummaryPanel.Visible = 'on';
            app.Panel_2.Visible = 'off';
            app.Panel_2.Enable = 'off';
            app.AngleofProjectionTextArea.Value = {num2str(app.theta,fspec)};
            app.InitialSpeedTextArea.Value = {num2str(app.speed,fspec)};
            app.FlightTimeTextArea.Value = {num2str(app.flight_time,fspec)};
            app.RangeTextArea.Value = {num2str(app.range,fspec)};
            app.MaxHeightTextArea.Value = {num2str(app.max_height,fspec)};
            time_of_max_height = (app.speed*sin(app.theta*(pi/180)))/app.gravity;
            app.TimeTextArea.Value = {num2str(time_of_max_height,fspec)};
            
            app.xEditField.Limits = [0 app.range];
            app.timeEditField.Limits = [0 app.flight_time];
        end
        
        function search_value(app,option)
            theta = app.theta*(pi/180);
            if option == 1
                %given x
                xi = app.xEditField.Value;
                time = xi/(cos(theta)*app.speed);
                yi = app.elevation + app.speed*time*sin(theta) - 0.5*app.gravity*(time^2);
            elseif option == 2
                %given time
                time = app.timeEditField.Value;
                xi = app.speed*time*cos(theta);
                yi = app.elevation + app.speed*time*sin(theta) - 0.5*app.gravity*(time^2);
            end
            app.timeEditField.Value = time;
            app.yEditField.Value = yi;
            app.xEditField.Value = xi;
        end
        
        function show_elavation_panel(app)
            app.InitialElevationPanel.Visible = 'on';
            app.InitialElevationPanel.Enable = 'on';
            app.Panel_2.Visible = 'off';
            app.Panel_2.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Run')
                start_simulation(app);
            end
        end

        % Value changed function: AngleofProjectionKnob
        function AngleofProjectionKnobValueChanged(app, event)
            value = app.AngleofProjectionKnob.Value;
            app.theta = value;
        end

        % Value changed function: InitialSpeedKnob
        function InitialSpeedKnobValueChanged(app, event)
            value = app.InitialSpeedKnob.Value;
            app.speed = value;
        end

        % Button pushed function: CloseSummaryButton
        function CloseSummaryButtonPushed(app, event)
            app.SimulationSummaryPanel.Enable = 'off';
            app.SimulationSummaryPanel.Visible = 'off';
            app.Panel_2.Visible = 'on';
            app.Panel_2.Enable = 'on';
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            if (app.timeEditField.Value == 0) & (app.xEditField.Value == 0)
                disp('Please Provide one value to start search')
            elseif (app.timeEditField.Value == 0)
                search_value(app,1);
            elseif (app.xEditField.Value == 0)
                search_value(app,2);
%             elseif isempty(app.timeTextArea.Value) &  isempty(app.XTextArea.Value)
%                 search_value(app,3);
            else
                disp('Please only provide one value');
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.timeEditField.Value = 0;
            app.yEditField.Value = 0;
            app.xEditField.Value = 0;
        end

        % Value changed function: ElevatedCheckBox
        function ElevatedCheckBoxValueChanged(app, event)
            value = app.ElevatedCheckBox.Value;
            show_elavation_panel(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.elevation = app.EnterElevationEditField.Value;
            app.InitialElevationPanel.Visible = 'off';
            app.InitialElevationPanel.Enable = 'off';
            app.Panel_2.Visible = 'on';
            app.Panel_2.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProjectilePanel = uipanel(app.UIFigure);
            app.ProjectilePanel.Title = 'Projectile';
            app.SimulationSummaryPanel = uipanel(app.UIFigure);
            app.SimulationSummaryPanel.Enable = 'off';
            app.SimulationSummaryPanel.Title = 'Simulation Summary';
            app.SimulationSummaryPanel.Visible = 'off';
            app.InputPanel = uipanel(app.SimulationSummaryPanel);
            app.InputPanel.Title = 'Input';
            app.AngleofProjectionTextAreaLabel = uilabel(app.InputPanel);
            app.AngleofProjectionTextAreaLabel.Text = 'Angle of Projection';
            app.AngleofProjectionTextArea = uitextarea(app.InputPanel);
            app.AngleofProjectionTextArea.Editable = 'off';
            app.InitialSpeedTextAreaLabel = uilabel(app.InputPanel);
            app.InitialSpeedTextAreaLabel.Text = 'Initial Speed';
            app.InitialSpeedTextArea = uitextarea(app.InputPanel);
            app.InitialSpeedTextArea.Editable = 'off';
            app.OutputPanel = uipanel(app.SimulationSummaryPanel);
            app.OutputPanel.Title = 'Output';
            app.MaxHeightLabel = uilabel(app.OutputPanel);
            app.MaxHeightLabel.Text = {'Max. '; 'Height'};
            app.MaxHeightTextArea = uitextarea(app.OutputPanel);
            app.MaxHeightTextArea.Editable = 'off';
            app.RangeTextAreaLabel = uilabel(app.OutputPanel);
            app.RangeTextAreaLabel.Text = 'Range';
            app.RangeTextArea = uitextarea(app.OutputPanel);
            app.RangeTextArea.Editable = 'off';
            app.TimeTextAreaLabel = uilabel(app.OutputPanel);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.OutputPanel);
            app.TimeTextArea.Editable = 'off';
            app.FlightTimeTextAreaLabel = uilabel(app.OutputPanel);
            app.FlightTimeTextAreaLabel.Text = {'Flight'; 'Time'; ''};
            app.FlightTimeTextArea = uitextarea(app.OutputPanel);
            app.FlightTimeTextArea.Editable = 'off';
            app.CloseSummaryButton = uibutton(app.SimulationSummaryPanel, 'push');
            app.CloseSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSummaryButtonPushed, true);
            app.CloseSummaryButton.Text = 'Close Summary';
            app.SearchValuePanel = uipanel(app.SimulationSummaryPanel);
            app.SearchValuePanel.Title = 'Search Value';
            app.SearchButton = uibutton(app.SearchValuePanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.xEditFieldLabel = uilabel(app.SearchValuePanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.SearchValuePanel, 'numeric');
            app.xEditField.Limits = [0 Inf];
            app.timeEditFieldLabel = uilabel(app.SearchValuePanel);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.SearchValuePanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.SearchValuePanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.SearchValuePanel, 'numeric');
            app.yEditField.Editable = 'off';
            app.ClearButton = uibutton(app.SearchValuePanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AngleofProjectionKnobLabel = uilabel(app.Panel_2);
            app.AngleofProjectionKnobLabel.Text = 'Angle of Projection';
            app.AngleofProjectionKnob = uiknob(app.Panel_2, 'continuous');
            app.AngleofProjectionKnob.Limits = [0 90];
            app.AngleofProjectionKnob.ValueChangedFcn = createCallbackFcn(app, @AngleofProjectionKnobValueChanged, true);
            app.InitialSpeedKnobLabel = uilabel(app.Panel_2);
            app.InitialSpeedKnobLabel.Text = 'Initial Speed';
            app.InitialSpeedKnob = uiknob(app.Panel_2, 'continuous');
            app.InitialSpeedKnob.ValueChangedFcn = createCallbackFcn(app, @InitialSpeedKnobValueChanged, true);
            app.Switch = uiswitch(app.Panel_2, 'rocker');
            app.Switch.Items = {'Off', 'Run'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ElevatedCheckBox = uicheckbox(app.Panel_2);
            app.ElevatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElevatedCheckBoxValueChanged, true);
            app.ElevatedCheckBox.Text = 'Elevated';
            app.Panel = uipanel(app.Panel_2);
            app.HeightTextAreaLabel = uilabel(app.Panel);
            app.HeightTextAreaLabel.Text = 'Height';
            app.HeightTextArea = uitextarea(app.Panel);
            app.HeightTextArea.Editable = 'off';
            app.DistanceTextAreaLabel = uilabel(app.Panel);
            app.DistanceTextAreaLabel.Text = 'Distance';
            app.DistanceTextArea = uitextarea(app.Panel);
            app.DistanceTextArea.Editable = 'off';
            app.InitialElevationPanel = uipanel(app.UIFigure);
            app.InitialElevationPanel.Enable = 'off';
            app.InitialElevationPanel.Title = 'Initial Elevation';
            app.InitialElevationPanel.Visible = 'off';
            app.EnterElevationEditFieldLabel = uilabel(app.InitialElevationPanel);
            app.EnterElevationEditFieldLabel.Text = 'Enter Elevation';
            app.EnterElevationEditField = uieditfield(app.InitialElevationPanel, 'numeric');
            app.EnterElevationEditField.Limits = [0 100];
            app.FinishButton = uibutton(app.InitialElevationPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.PartofMatlabPortfolioofJodEspanolaLabel = uilabel(app.UIFigure);
            app.PartofMatlabPortfolioofJodEspanolaLabel.Text = 'Part of Matlab Portfolio of Jod Espanola';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end