classdef wallet_tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AddYearPanel           matlab.ui.container.Panel
        CancelButton           matlab.ui.control.Button
        AddButton              matlab.ui.control.Button
        YearEditField          matlab.ui.control.NumericEditField
        YearEditFieldLabel     matlab.ui.control.Label
        AddYearButton          matlab.ui.control.Button
        WalletTrackerLabel     matlab.ui.control.Label
        Tree                   matlab.ui.container.CheckBoxTree
        Node                   matlab.ui.container.TreeNode
        Q1Node                 matlab.ui.container.TreeNode
        JanuaryNode            matlab.ui.container.TreeNode
        FebruaryNode           matlab.ui.container.TreeNode
        MarchNode              matlab.ui.container.TreeNode
        Q2Node                 matlab.ui.container.TreeNode
        AprilNode              matlab.ui.container.TreeNode
        MayNode                matlab.ui.container.TreeNode
        JuneNode               matlab.ui.container.TreeNode
        Q3Node                 matlab.ui.container.TreeNode
        JulyNode               matlab.ui.container.TreeNode
        AugustNode             matlab.ui.container.TreeNode
        SeptemberNode          matlab.ui.container.TreeNode
        Q4Node                 matlab.ui.container.TreeNode
        OctoberNode            matlab.ui.container.TreeNode
        NovemberNode           matlab.ui.container.TreeNode
        DecemberNode           matlab.ui.container.TreeNode
        TabGroup               matlab.ui.container.TabGroup
        SummaryTab             matlab.ui.container.Tab
        Panel_7                matlab.ui.container.Panel
        ExpenseTab             matlab.ui.container.Tab
        Tree5_2                matlab.ui.container.Tree
        Node_3                 matlab.ui.container.TreeNode
        Node2_6                matlab.ui.container.TreeNode
        Node3_6                matlab.ui.container.TreeNode
        Node4_6                matlab.ui.container.TreeNode
        Panel_6                matlab.ui.container.Panel
        IncomeTab              matlab.ui.container.Tab
        Tree5                  matlab.ui.container.Tree
        Node_2                 matlab.ui.container.TreeNode
        Node2_5                matlab.ui.container.TreeNode
        Node3_5                matlab.ui.container.TreeNode
        Node4_5                matlab.ui.container.TreeNode
        Panel_5                matlab.ui.container.Panel
        EditTab                matlab.ui.container.Tab
        IncomePanel_2          matlab.ui.container.Panel
        Tree2_3                matlab.ui.container.Tree
        ShowIncomeNode_2       matlab.ui.container.TreeNode
        Node2_3                matlab.ui.container.TreeNode
        Node3_3                matlab.ui.container.TreeNode
        Node4_3                matlab.ui.container.TreeNode
        Panel_3                matlab.ui.container.Panel
        AddIncomeButton_2      matlab.ui.control.Button
        ValueEditField_3       matlab.ui.control.NumericEditField
        ValueEditField_3Label  matlab.ui.control.Label
        NameTextArea_3         matlab.ui.control.TextArea
        NameTextArea_3Label    matlab.ui.control.Label
        ExpensePanel_2         matlab.ui.container.Panel
        Tree2_4                matlab.ui.container.Tree
        ShowExpenseNode_2      matlab.ui.container.TreeNode
        Node2_4                matlab.ui.container.TreeNode
        Node3_4                matlab.ui.container.TreeNode
        Node4_4                matlab.ui.container.TreeNode
        Panel_4                matlab.ui.container.Panel
        AddExpenseButton_2     matlab.ui.control.Button
        ValueEditField_4       matlab.ui.control.NumericEditField
        ValueEditField_4Label  matlab.ui.control.Label
        NameTextArea_4         matlab.ui.control.TextArea
        NameTextArea_4Label    matlab.ui.control.Label
    end

    
    properties (Access = public)
        add_year_popup = false % if add year popup message is visible
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddYearButton
        function AddYearButtonPushed(app, event)
            app.add_year_popup = ~app.add_year_popup;
            if app.add_year_popup
                app.AddYearPanel.Enable = 'on';
                app.AddYearPanel.Visible = 'on';
            else
                app.AddYearPanel.Enable = 'off';
                app.AddYearPanel.Visible = 'off';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.AddYearPanel.Enable = 'off';
            app.AddYearPanel.Visible = 'off';
            app.add_year_popup = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.Panel_7 = uipanel(app.SummaryTab);
            app.ExpenseTab = uitab(app.TabGroup);
            app.ExpenseTab.Title = 'Expense';
            app.Panel_6 = uipanel(app.ExpenseTab);
            app.Tree5_2 = uitree(app.ExpenseTab);
            app.Node_3 = uitreenode(app.Tree5_2);
            app.Node_3.Text = 'Node';
            app.Node2_6 = uitreenode(app.Node_3);
            app.Node2_6.Text = 'Node2';
            app.Node3_6 = uitreenode(app.Node_3);
            app.Node3_6.Text = 'Node3';
            app.Node4_6 = uitreenode(app.Node_3);
            app.Node4_6.Text = 'Node4';
            app.IncomeTab = uitab(app.TabGroup);
            app.IncomeTab.Title = 'Income';
            app.Panel_5 = uipanel(app.IncomeTab);
            app.Tree5 = uitree(app.IncomeTab);
            app.Node_2 = uitreenode(app.Tree5);
            app.Node_2.Text = 'Node';
            app.Node2_5 = uitreenode(app.Node_2);
            app.Node2_5.Text = 'Node2';
            app.Node3_5 = uitreenode(app.Node_2);
            app.Node3_5.Text = 'Node3';
            app.Node4_5 = uitreenode(app.Node_2);
            app.Node4_5.Text = 'Node4';
            app.EditTab = uitab(app.TabGroup);
            app.EditTab.Title = 'Edit';
            app.ExpensePanel_2 = uipanel(app.EditTab);
            app.ExpensePanel_2.Title = 'Expense';
            app.Panel_4 = uipanel(app.ExpensePanel_2);
            app.NameTextArea_4Label = uilabel(app.Panel_4);
            app.NameTextArea_4Label.Text = 'Name';
            app.NameTextArea_4 = uitextarea(app.Panel_4);
            app.ValueEditField_4Label = uilabel(app.Panel_4);
            app.ValueEditField_4Label.Text = 'Value';
            app.ValueEditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.AddExpenseButton_2 = uibutton(app.Panel_4, 'push');
            app.AddExpenseButton_2.Text = 'Add Expense';
            app.Tree2_4 = uitree(app.ExpensePanel_2);
            app.ShowExpenseNode_2 = uitreenode(app.Tree2_4);
            app.ShowExpenseNode_2.Text = 'Show Expense';
            app.Node2_4 = uitreenode(app.ShowExpenseNode_2);
            app.Node2_4.Text = 'Node2';
            app.Node3_4 = uitreenode(app.ShowExpenseNode_2);
            app.Node3_4.Text = 'Node3';
            app.Node4_4 = uitreenode(app.ShowExpenseNode_2);
            app.Node4_4.Text = 'Node4';
            app.IncomePanel_2 = uipanel(app.EditTab);
            app.IncomePanel_2.Title = 'Income';
            app.Panel_3 = uipanel(app.IncomePanel_2);
            app.NameTextArea_3Label = uilabel(app.Panel_3);
            app.NameTextArea_3Label.Text = 'Name';
            app.NameTextArea_3 = uitextarea(app.Panel_3);
            app.ValueEditField_3Label = uilabel(app.Panel_3);
            app.ValueEditField_3Label.Text = 'Value';
            app.ValueEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.AddIncomeButton_2 = uibutton(app.Panel_3, 'push');
            app.AddIncomeButton_2.Text = 'Add Income';
            app.Tree2_3 = uitree(app.IncomePanel_2);
            app.ShowIncomeNode_2 = uitreenode(app.Tree2_3);
            app.ShowIncomeNode_2.Text = 'Show Income';
            app.Node2_3 = uitreenode(app.ShowIncomeNode_2);
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.ShowIncomeNode_2);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.ShowIncomeNode_2);
            app.Node4_3.Text = 'Node4';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = '2022';
            app.Q1Node = uitreenode(app.Node);
            app.Q1Node.Text = 'Q1';
            app.JanuaryNode = uitreenode(app.Q1Node);
            app.JanuaryNode.Text = 'January';
            app.FebruaryNode = uitreenode(app.Q1Node);
            app.FebruaryNode.Text = 'February';
            app.MarchNode = uitreenode(app.Q1Node);
            app.MarchNode.Text = 'March';
            app.Q2Node = uitreenode(app.Node);
            app.Q2Node.Text = 'Q2';
            app.AprilNode = uitreenode(app.Q2Node);
            app.AprilNode.Text = 'April';
            app.MayNode = uitreenode(app.Q2Node);
            app.MayNode.Text = 'May';
            app.JuneNode = uitreenode(app.Q2Node);
            app.JuneNode.Text = 'June';
            app.Q3Node = uitreenode(app.Node);
            app.Q3Node.Text = 'Q3';
            app.JulyNode = uitreenode(app.Q3Node);
            app.JulyNode.Text = 'July';
            app.AugustNode = uitreenode(app.Q3Node);
            app.AugustNode.Text = 'August';
            app.SeptemberNode = uitreenode(app.Q3Node);
            app.SeptemberNode.Text = 'September';
            app.Q4Node = uitreenode(app.Node);
            app.Q4Node.Text = 'Q4';
            app.OctoberNode = uitreenode(app.Q4Node);
            app.OctoberNode.Text = 'October';
            app.NovemberNode = uitreenode(app.Q4Node);
            app.NovemberNode.Text = 'November';
            app.DecemberNode = uitreenode(app.Q4Node);
            app.DecemberNode.Text = 'December';
            app.WalletTrackerLabel = uilabel(app.UIFigure);
            app.WalletTrackerLabel.Text = 'Wallet Tracker';
            app.AddYearButton = uibutton(app.UIFigure, 'push');
            app.AddYearButton.ButtonPushedFcn = createCallbackFcn(app, @AddYearButtonPushed, true);
            app.AddYearButton.Text = 'Add Year';
            app.AddYearPanel = uipanel(app.UIFigure);
            app.AddYearPanel.Enable = 'off';
            app.AddYearPanel.Title = 'Add Year';
            app.AddYearPanel.Visible = 'off';
            app.YearEditFieldLabel = uilabel(app.AddYearPanel);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.AddYearPanel, 'numeric');
            app.AddButton = uibutton(app.AddYearPanel, 'push');
            app.AddButton.Text = 'Add';
            app.CancelButton = uibutton(app.AddYearPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wallet_tracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end